classdef RobotController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ReadCurrentPosButton   matlab.ui.control.Button
        VelocityModeButton     matlab.ui.control.Button
        PositionModeButton     matlab.ui.control.Button
        GotoPointSmoothButton  matlab.ui.control.Button
        PickwithAngleButton    matlab.ui.control.Button
        PickandPutButton       matlab.ui.control.Button
        PutButton              matlab.ui.control.Button
        PickButton             matlab.ui.control.Button
        Z_PutEditField         matlab.ui.control.NumericEditField
        Z_PutEditFieldLabel    matlab.ui.control.Label
        Y_PutEditField         matlab.ui.control.NumericEditField
        Y_PutEditFieldLabel    matlab.ui.control.Label
        X_PutEditField         matlab.ui.control.NumericEditField
        X_PutEditFieldLabel    matlab.ui.control.Label
        Z_PickEditField        matlab.ui.control.NumericEditField
        Z_PickEditFieldLabel   matlab.ui.control.Label
        Y_PickEditField        matlab.ui.control.NumericEditField
        Y_PickEditFieldLabel   matlab.ui.control.Label
        X_PickEditField        matlab.ui.control.NumericEditField
        X_PickEditFieldLabel   matlab.ui.control.Label
        GotowithAngleButton    matlab.ui.control.Button
        AngleWEditField        matlab.ui.control.NumericEditField
        AngleWEditFieldLabel   matlab.ui.control.Label
        GripperReleaseButton   matlab.ui.control.Button
        GripperHoldButton      matlab.ui.control.Button
        ClearButton            matlab.ui.control.Button
        PointZEditField        matlab.ui.control.NumericEditField
        PointZEditFieldLabel   matlab.ui.control.Label
        Y_GridEditField        matlab.ui.control.NumericEditField
        Y_GridEditFieldLabel   matlab.ui.control.Label
        X_GridEditField        matlab.ui.control.NumericEditField
        X_GridEditFieldLabel   matlab.ui.control.Label
        GotoPointButton        matlab.ui.control.Button
        RunButton              matlab.ui.control.Button
        DisconnectButton       matlab.ui.control.Button
        ConnectButton          matlab.ui.control.Button
        EditField5             matlab.ui.control.NumericEditField
        EditField5Label        matlab.ui.control.Label
        Slider5                matlab.ui.control.Slider
        Slider5Label           matlab.ui.control.Label
        EditField4             matlab.ui.control.NumericEditField
        EditField4Label        matlab.ui.control.Label
        Slider4                matlab.ui.control.Slider
        Slider4Label           matlab.ui.control.Label
        EditField3             matlab.ui.control.NumericEditField
        EditField3Label        matlab.ui.control.Label
        Slider3                matlab.ui.control.Slider
        Slider3Label           matlab.ui.control.Label
        EditField2             matlab.ui.control.NumericEditField
        EditField2Label        matlab.ui.control.Label
        Slider2                matlab.ui.control.Slider
        Slider2Label           matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        EditFieldLabel         matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        SliderLabel            matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        lib_name
        port_num
        PROTOCOL_VERSION = 2.0
        BAUDRATE = 1000000
        DEVICENAME = "COM22"
    
        % IDs
        DXL_ID1 = 11
        DXL_ID2 = 12
        DXL_ID3 = 13
        DXL_ID4 = 14
        DXL_GRIP = 15
        DXL_IDS
    
        % addresses
        ADDR_MAX_POS = 48
        ADDR_MIN_POS = 52
        ADDR_PRO_TORQUE_ENABLE = 64
        ADDR_PRO_GOAL_POSITION = 116
        ADDR_PRO_PRESENT_POSITION = 132
        ADDR_PRO_OPERATING_MODE = 11
        ADDR_PRO_PROFILE_VELOCITY = 112
        ADDR_PRO_GOAL_VELOCITY = 104
        ADDR_PRO_PROFILE_ACCELERATION = 108
    
        % constants
        TORQUE_ENABLE = 1
        TORQUE_DISABLE = 0
        MAX_POS = 3400
        MIN_POS = 600
        VELOCITY = 100
        ACCEL = 20
        VELOCITY_DEG = 130
        ACCEL_DEG = 300
        proportionalConstant = 0.1

        theta_limit_deg = [-120,120;...
                            -90,90;...
                            -90,90;...
                            -115,90;
                            -90,90]

    
        isConnected = false
        updateTimer
        
        thetaOffset = [0, pi/2-17.5*pi/180, -pi/2+17.5*pi/180, 0];
        thetaSign = [1 -1 -1 -1 1];  % 每个关节的方向（第5个是夹爪，先不反）
        theta_current = [0,0,0,0,0];
        
        GRIP_HOLD_POS = 35;
        GRIP_RELEASE_POS = -60;

        draw_enable = false;

        hArmLine;
        hGripperTrace;
        gripperTraceData;
        hRealTrace;
        realTraceData;

        vmax_deg = 60;
        alpha_plan_deg = 180;
        alpha_lim_deg = 180;

        sync_omega_max = [0,0,0,0]
        sync_alpha_max = [0,0,0,0]
        vel_last = [0,0,0,0]
        theta_tolerance = 0.5
        vel_tolerance = 0.5
        qdot_max_degps = 200
        Kp_cart = 1
        vmax_cart = 0.1
        dls_lambda = 0.01
        p_tolerance = 1e-3
        alpha_vel_degps2 = 500
    end

  
    methods (Access = private)
            function redraw(app)
                % 读角度（度->弧度）
                th_deg = [app.EditField.Value, ...
                          app.EditField2.Value, ...
                          app.EditField3.Value, ...
                          app.EditField4.Value,];
                th = deg2rad(th_deg);
            
                % FK 得到点
                P = app.fk_points(th);  % 3x5
                %disp("Wrist Position: ")
                %disp(P(:,4))
                gripper = P(:,5).';   % 1x3

                % 记录轨迹
                app.gripperTraceData = [app.gripperTraceData; gripper];

                % 画图
                %cla(app.UIAxes);
                set(app.hArmLine, 'XData', P(1,:), 'YData', P(2,:), 'ZData', P(3,:));

                % 更新红色轨迹
                if(app.draw_enable)
                    set(app.hGripperTrace, ...
                        'XData', app.gripperTraceData(:,1), ...
                        'YData', app.gripperTraceData(:,2), ...
                        'ZData', app.gripperTraceData(:,3));
                end

            end


            % ===== 通用回调：Slider拖动时 =====
            function ThetaSliderChanging(app, event, idx)
                val = event.Value;
                app.setTheta(idx, val, true);  % true=来自滑动条
            end
        
            % ===== 通用回调：Slider松手/或EditField改值 =====
            function ThetaControlChanged(app, idx, val)

                app.setTheta(idx, val, false);
            end

            function val_lim = setTheta_sim(app, idx, val, ~)

                app.theta_current(idx) = val;
                val = max(app.theta_limit_deg(idx,1), min(val, app.theta_limit_deg(idx,2)));           
                % 2) 同步 slider <-> editfield
                switch idx
                    case 1
                        app.Slider.Value  = val; app.EditField.Value  = val;
                    case 2
                        app.Slider2.Value = val; app.EditField2.Value = val;
                    case 3
                        app.Slider3.Value = val; app.EditField3.Value = val;
                    case 4
                        app.Slider4.Value = val; app.EditField4.Value = val;
                    case 5
                        app.Slider5.Value = val; app.EditField5.Value = val;
                end
            
                app.redraw();

                val_lim = val;
            end

            function setTheta(app, idx, val, ~)

                val = app.setTheta_sim(idx, val, false);
            
                % 4) 再驱动实物（只有已连接才写）
                if ~app.isConnected
                    return;
                end

                id = app.DXL_IDS(idx);
                %将profile velocity和acceleration设置为默认值
                app.set_profile_velocity_degps(id, app.VELOCITY);
                app.set_profile_acceleration_degps2(id, app.ACCEL);
            
                % 6) idx -> 真正的电机ID（推荐用数组映射，别用 idx+10 猜）
                val_corr = app.thetaSign(idx) * val;

                % 7) 度 -> position
                goal_pos = app.deg2pos(val_corr);

                % 8) 写入电机（加 try/catch 防止异常卡UI）
                try
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_GOAL_POSITION, goal_pos);
                    if(idx == 4)
                        disp(goal_pos)
                    end
                catch ME
                    % 写失败就断开标记，避免后续一直卡
                    disp(ME.message);
                    app.isConnected = false;
                end
            end


            function setTheta_sync(app, idx, val, ~, v_max, a_max)

                fprintf("Moving motor %d with vel: %.3f, accel: %.3f\n", idx, v_max, a_max);
                val = app.setTheta_sim(idx, val, false);

                if ~app.isConnected
                    return;
                end

                %更改profile速度
                app.set_profile_velocity_degps(idx, v_max);
                app.set_profile_acceleration_degps2(idx, a_max);
                fprintf("Motor %d Moving with speed: %d, accel %d\n", idx, v_max, a_max);
            
                % 6) idx -> 真正的电机ID（推荐用数组映射，别用 idx+10 猜）
                id = app.DXL_IDS(idx);   % [11 12 13 14 15]
                val_corr = app.thetaSign(idx) * val;

                % 7) 度 -> position
                goal_pos = app.deg2pos(val_corr);

                % 8) 写入电机（加 try/catch 防止异常卡UI）
                try
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_GOAL_POSITION, goal_pos);
                catch ME
                    % 写失败就断开标记，避免后续一直卡
                    disp(ME.message);
                    app.isConnected = false;
                end
            end

            function [v_out, a_out] = ratio(~, s_max, s_c, v_max, a_max)
                r = abs(s_c)/abs(s_max);
                v_out = v_max*r;
                a_out = a_max*r;
            end

            function set_group_v_a_deg(app, velocity, acceleration)
                ids = app.DXL_IDS(1:4);
                for k = 1:numel(ids)
                    app.set_profile_velocity_degps(ids(k), velocity(k));
                    app.set_profile_acceleration_degps2(ids(k), acceleration(k));
                end
            end

            function set_group_theta(app, theta_f)

                ids = app.DXL_IDS(1:4);
                theta_f_lim = zeros(1,4);

                for index = 1:numel(ids)
                    theta_f_lim(index) = app.setTheta_sim(index, theta_f(index), false);
                end  

                v_sync = zeros(1,4);
                a_sync = zeros(1,4);

                start_pos = app.read_all_current_pos();
                distance = theta_f_lim-start_pos;

                abs_distance = abs(distance);

                Dmax = max(abs_distance);
                if Dmax < 1e-6
                    return
                end
                
                for index = 1:numel(ids)
                    [v_sync(index), a_sync(index)] = app.ratio(Dmax, distance(index), app.VELOCITY_DEG, app.ACCEL_DEG);
                end
                
                app.set_group_v_a_deg(v_sync, a_sync);

                theta_f_corr = app.thetaSign(1:4) .* theta_f_lim;

                for index = 1:numel(ids)
                    goal_pos = app.deg2pos(theta_f_corr(index));
                    try
                        write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(index), app.ADDR_PRO_GOAL_POSITION, goal_pos);
                    catch ME
                        % 写失败就断开标记，避免后续一直卡
                        disp(ME.message);
                        app.isConnected = false;
                    end
                end
            end


            function set_omega(app, theta_f)
                ids = app.DXL_IDS(1:4);
                start_pos = app.read_all_current_pos();
                distance = theta_f-start_pos;
                  
                absolute_distance = abs(distance);

                current_pos = start_pos;
                max_alpha = app.alpha_plan_deg;

                Dmax = max(absolute_distance);
                direction = sign(distance);
                if Dmax < 1e-9
                    % 已经到位，直接停速度
                    for k = 1:numel(ids)
                        write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_GOAL_VELOCITY, 0);
                    end
                    app.vel_last = zeros(1,4);
                    disp("Already at target.");
                    return;
                end

                for k = 1:numel(ids) 
                    max_alpha_i = max_alpha * absolute_distance(k) / Dmax;
                    app.sync_alpha_max(k) = max_alpha_i;
                end

                vel = zeros(1,4);
                vel_units = zeros(1,4,'uint32');
                t0 = tic;

                while any(abs(theta_f - current_pos) > app.theta_tolerance)
                    current_pos = app.read_all_current_pos();

                    dt = toc(t0);
                    dt = min(max(dt, 0.001), 0.05); 

                    for k = 1:numel(ids)
                        v_cmd= accLimitVel(app, current_pos(k), theta_f(k), app.sync_alpha_max(k), dt, app.vel_last(k), direction(k));
                        vel(k) = v_cmd;
                        vel_units(k) = degps2velTick(app, v_cmd);
                    end

                    for k = 1:numel(ids)
                        disp(typecast(vel_units(k), 'uint32'));
                        if(vel_units(k) ~= 0)
                            write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_GOAL_VELOCITY, uint32(vel_units(k)));
                        end
                    end
                    t0 = tic;

                    app.vel_last = vel;
                    direction = sign(theta_f-current_pos);

                    pos_ok = all(abs(theta_f - current_pos) <= app.theta_tolerance);
                    vel_ok = all(abs(vel) <= app.vel_tolerance);   % 比如 0.5 deg/s 或更小
                    if pos_ok && vel_ok
                        break;
                    end
                    pause(0.02)
                end

                for k = 1:numel(ids)
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_GOAL_VELOCITY, 0);
                end
                app.vel_last = zeros(1,4);
                disp("Smooth Movement Complete");
            end


            function v_cmd = accLimitVel(app, theta, thetaf, alpha_plan, dt, v_last, direction)
                v_max = v_last + alpha_plan*dt*direction;
                v_prop = app.proportionalVel(theta, thetaf);
                if abs(v_prop)> abs(v_max)
                    v_cmd = v_max;
                else
                    v_cmd = v_prop;
                end
            end

            function v = proportionalVel(app, theta, thetaf)
                v = app.proportionalConstant*(thetaf-theta);
            end


            function T = dh_T(~, alpha, a, d, theta)
                % Standard DH (Craig):
                % T = RotZ(theta) * TransZ(d) * TransX(a) * RotX(alpha)
            
                ca = cos(alpha); sa = sin(alpha);
                ct = cos(theta); st = sin(theta);
            
                T = [ ct   -st*ca   st*sa   a*ct;
                      st    ct*ca  -ct*sa   a*st;
                      0       sa      ca      d;
                      0        0       0      1];
            end
            function Tall = fk_T_all(app, theta)
                % theta: 1x4 (rad) = [t1 t2 t3 t4]
                % returns Tall: 4x4x5, Tall(:,:,1)=I, Tall(:,:,k+1)=T0k
            
                % ---- Your DH table ----
                alpha = [deg2rad(90), 0, 0, 0];   % alpha0..alpha3
                a     = [0, 0.130, 0.124, 0.126]; % a0..a3
                d     = [0.077, 0, 0, 0];         % d1..d4
            
                % ---- Joint angle offsets (rad) ----
                % 让第3关节零位时默认向下 90°：   % 若方向反了，把 -pi/2 改成 +pi/2
            
                % ensure theta is 1x4
                theta = reshape(theta, 1, []);
                if numel(theta) ~= 4
                    error("fk_T_all: theta must be 1x4 (rad).");
                end
            
                theta_eff = theta + app.thetaOffset;
            
                Tall = zeros(4,4,5);
                Tall(:,:,1) = eye(4);
            
                T = eye(4);
                for i = 1:4
                    Ti = app.dh_T(alpha(i), a(i), d(i), theta_eff(i));
                    T = T * Ti;
                    Tall(:,:,i+1) = T;
                end
            end

            function p = fk_pos(app, theta)
                % theta: 1x4 rad
                Tall = app.fk_T_all(theta);
                p = Tall(1:3,4,5);  % T0_4 的平移（Tall(:,:,5)是末端）
            end

            function Jv = jacobian_pos(app, theta)
                % theta: 1x4 rad
                Tall = app.fk_T_all(theta);
            
                pe = Tall(1:3,4,5);      % end-effector position
                Jv = zeros(3,4);
            
                for i = 1:4
                    Ti_1 = Tall(:,:,i);          % T0_(i-1)
                    zi_1 = Ti_1(1:3,3);          % z-axis of frame (i-1) in base
                    pi_1 = Ti_1(1:3,4);          % origin of frame (i-1) in base
            
                    Jv(:,i) = cross(zi_1, (pe - pi_1));
                end
            end

   
            function P = fk_points(app, theta)
                % Returns joint positions for plotting links
                % P: 3x5 (base + 4 joints/end-effector)
                Tall = app.fk_T_all(theta);
            
                P = zeros(3,5);
                for k = 1:5
                    P(:,k) = Tall(1:3,4,k);
                end
            end

            function move_ee_parallel_ground(app, p_target)
            % 雅可比速度法：末端位置跟踪 + 工具始终平行地面（pitch固定）
            % 输入:
            %   p_target: [x y z] 末端目标位置（单位：m 或 mm，与你 fk/jacobian 一致）
            %
            % 依赖你需要提供/已有:
            %   p = fk_pos(app, q)              -> 3x1 末端位置
            %   Jv = jacobian_pos(app, q)       -> 3x4 位置雅可比（基座系），使得 p_dot = Jv*q_dot
            %
            % 注意：
            %   q 是关节角（deg 或 rad 任选，但要与你的 jacobian_pos、fk_pos 一致）
            %   q_dot 最终下发给电机要用 deg/s（你的 degps2velTick 也是按 deg/s）
            
                ids = app.DXL_IDS(1:4);
            
                % 读取当前关节角（你的 read_all_current_pos 返回单位看你实现：通常是 deg）
                q = app.read_all_current_pos();  % 1x4
            
                % 固定“工具平行地面”的常数 c：用当前姿态锁定
                % 如果你希望锁成某个指定pitch，把 c 改成你想要的常数即可
                c = q(2) + q(3) + q(4);
            
                % 控制参数（按你的系统调）
                Kp = app.Kp_cart;          % 例如 1~5 (1/s)，用于 p_dot_des = Kp*(p_target - p)
                v_max = app.vmax_cart;     % 末端最大线速度（m/s 或 mm/s，与你单位一致）
                lambda = app.dls_lambda;   % DLS阻尼，例如 0.01~0.1
                qdot_max = app.qdot_max_degps; % 1x4 每关节最大速度 deg/s（可选，没有就用一个标量）
            
                % 循环时钟
                t0 = tic;
                last_vel = app.vel_last;   % 1x4 deg/s
            
                while true
                    % 读当前关节角
                    q = app.read_all_current_pos();  % 1x4
            
                    % 强制满足平行地面约束（位置控制时建议在“解算/积分侧”保证一致）
                    % 注意：这里只是用于计算J和FK时的“姿态一致”，电机实际位置由控制闭环收敛
                    q(4) = c - q(2) - q(3);
            
                    % 末端当前位置
                    p = fk_pos(app, q(:));  % 3x1
            
                    % 位置误差
                    e = (p_target(:) - p);  % 3x1
            
                    % 退出条件：末端到位 + 关节速度很小
                    if norm(e) < app.p_tolerance
                        if all(abs(last_vel) < app.vel_tolerance)
                            break;
                        end
                    end
            
                    % 计算dt
                    dt = toc(t0);
                    dt = min(max(dt, 0.001), 0.05);
                    t0 = tic;
            
                    % 任务空间期望线速度（最简单：比例项）
                    p_dot_des = Kp * e;  % 3x1
            
                    % 末端线速度限幅
                    spd = norm(p_dot_des);
                    if spd > v_max
                        p_dot_des = (v_max / (spd + 1e-9)) * p_dot_des;
                    end
            
                    % 位置雅可比 3x4
                    Jv = jacobian_pos(app, q(:));  % 3x4, p_dot = Jv*q_dot
            
                    % ---- 固定姿态：用降阶雅可比（3x3）解 q1,q2,q3 ----
                    J1 = Jv(:,1); J2 = Jv(:,2); J3 = Jv(:,3); J4 = Jv(:,4);
                    Jr = [ J1, (J2 - J4), (J3 - J4) ];   % 3x3
                    % 关节独立变量：qdot_r = [q1dot q2dot q3dot]'
            
                    % DLS 求解：qdot_r = Jr' * inv(Jr*Jr' + lambda^2 I) * p_dot_des
                    A = (Jr*Jr' + (lambda^2)*eye(3));
                    qdot_r = Jr' * (A \ p_dot_des);   % 3x1
            
                    % 恢复4关节速度：q4dot = -q2dot - q3dot
                    qdot = zeros(4,1);
                    qdot(1) = qdot_r(1);
                    qdot(2) = qdot_r(2);
                    qdot(3) = qdot_r(3);
                    qdot(4) = -qdot_r(2) - qdot_r(3);
            
                    % ---- 关节速度单位统一到 deg/s（如果你的 q 是 deg，这里 qdot 默认就是 deg/s）----
                    % 若你内部用 rad，则要在这里做 rad/s -> deg/s： qdot_degps = rad2deg(qdot)
            
                    qdot_cmd = qdot(:)'; % 1x4
            
                    % 关节速度限幅（可选）
                    if ~isempty(qdot_max)
                        if isscalar(qdot_max)
                            qdot_cmd = max(min(qdot_cmd, qdot_max), -qdot_max);
                        else
                            qdot_cmd = max(min(qdot_cmd, qdot_max(:)'), -qdot_max(:)');
                        end
                    end
            
                    % ---- 加速度/速度平滑（复用你原来的 accLimitVel 逻辑）----
                    vel = zeros(1,4);
                    vel_units = zeros(1,4,'uint32');
            
                    for k = 1:4
                        % 这里把“目标速度”当成雅可比求出来的 qdot_cmd(k)
                        % 你原 accLimitVel 是按“当前角度、目标角度”规划的；
                        % 速度控制下更合理的是对速度做加速度限幅。
                        % 如果你没有现成的 velAccLimit，可以先用一个简单一阶限幅：
                        v_des = qdot_cmd(k);
            
                        amax = app.alpha_vel_degps2; % 关节角加速度上限 (deg/s^2)，你自己设
                        dv = v_des - last_vel(k);
                        dv_lim = max(min(dv, amax*dt), -amax*dt);
                        v_cmd = last_vel(k) + dv_lim;
            
                        vel(k) = v_cmd;
                        vel_units(k) = degps2velTick(app, v_cmd);
                    end
            
                    % 下发速度
                    for k = 1:4
                        write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), ...
                            app.ADDR_PRO_GOAL_VELOCITY, uint32(vel_units(k)));
                    end
            
                    % 记录
                    last_vel = vel;
                    app.vel_last = last_vel;
            
                    pause(0.02);
                end
            
                % 停止
                for k = 1:4
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_GOAL_VELOCITY, 0);
                end
                app.vel_last = zeros(1,4);
                disp("Jacobian velocity move complete (tool parallel to ground).");
            end


            function name = getLibName(~)
                if strcmp(computer,'PCWIN')
                    name = 'dxl_x86_c';
                elseif strcmp(computer,'PCWIN64')
                    name = 'dxl_x64_c';
                elseif strcmp(computer,'GLNX86')
                    name = 'libdxl_x86_c';
                elseif strcmp(computer,'GLNXA64')
                    name = 'libdxl_x64_c';
                elseif strcmp(computer,'MACI64')
                    name = 'libdxl_mac_c';
                else
                    error("Unsupported platform: %s", computer);
                end
            end

            function connectDxl(app)
                if app.isConnected
                    return;
                end
            
                app.lib_name = app.getLibName();
            
                % Load Libraries
                if ~libisloaded(app.lib_name)
                    loadlibrary(app.lib_name, 'dynamixel_sdk.h', ...
                        'addheader','port_handler.h', ...
                        'addheader','packet_handler.h');
                end
            
                % port/packet init
                app.port_num = portHandler(char(app.DEVICENAME));
                packetHandler();
            
                % Open port
                if ~openPort(app.port_num)
                    error("Failed to open port %s", app.DEVICENAME);
                end
            
                % Set baudrate
                if ~setBaudRate(app.port_num, app.BAUDRATE)
                    error("Failed to set baudrate");
                end
            
                % Disable torque
                for id = app.DXL_IDS
                    write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_TORQUE_ENABLE, app.TORQUE_DISABLE);
                end
            
                % Position control mode
                for id = app.DXL_IDS
                    write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_OPERATING_MODE, 3);
                end
            
                % Enable torque
                for id = app.DXL_IDS
                    write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_TORQUE_ENABLE, app.TORQUE_ENABLE);
                end

                % Set Speed
                for id = app.DXL_IDS
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_PROFILE_VELOCITY, app.VELOCITY);
                end

                %Set Acceleration
                for id = app.DXL_IDS
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_PROFILE_ACCELERATION, app.ACCEL);
                end

            
                % Limits + center
                for id = app.DXL_IDS
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_MAX_POS, app.MAX_POS);
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_MIN_POS, app.MIN_POS);
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_GOAL_POSITION, 2048);
                end
                pause(0.5);
            
                app.isConnected = true;
            end

            function disconnectDxl(app)
                if ~app.isConnected
                    return;
                end
            
                % stop timer first
                if ~isempty(app.updateTimer) && isvalid(app.updateTimer)
                    stop(app.updateTimer);
                end
            
                % Disable torque
                for id = app.DXL_IDS
                    write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_TORQUE_ENABLE, app.TORQUE_DISABLE);
                end
            
                % Close port
                closePort(app.port_num);
            
                % Unload library
                if libisloaded(app.lib_name)
                    unloadlibrary(app.lib_name);
                end
            
                app.isConnected = false;
            end
           
            function onTimerTick(app)
                if ~app.isConnected
                    return;
                end
            
            
                %goal = app.deg2pos(th_deg);
            
                % 2) 写入五个电机
                ids = app.DXL_IDS;
                %for k = 1:numel(ids)
                %    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_GOAL_POSITION, goal(k));
                %end
            
                % 3) 读回位置（用于显示/3D）
                pos = zeros(1,numel(ids));
                for k = 1:numel(ids)
                    pos(k) = read4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_PRESENT_POSITION);
                end
            
                % 4) 用前4个关节做 FK（你给的 DH 表是4轴）
                theta_rad = app.pos2rad(pos(1:4));
                P = app.fk_points(theta_rad);   % 用我前面给你的 DH FK 函数
            
                cla(app.UIAxes);
                plot3(app.UIAxes, P(1,:),P(2,:),P(3,:), '-o','LineWidth',2);
                grid(app.UIAxes,'on'); axis(app.UIAxes,'equal'); view(app.UIAxes,3);
                drawnow limitrate;
            end
            
            function th = pos2rad(~, pos)
                th = (double(pos) - 2048) / 4096 * 2*pi;
            end
            
            function pos = rad2pos(~, th)
                pos = round(2048 + th/(2*pi)*4096);
            end
            
            function pos = deg2pos(app, th_deg)
                th = deg2rad(th_deg);
                pos = app.rad2pos(th);
            
                % 安全限幅（可选）
                pos = max(app.MIN_POS, min(app.MAX_POS, pos));
            end

            function deg = pos2deg(app, pos)
                th = app.pos2rad(pos);
                deg = rad2deg(th);
            end

            function pts = interp(~, p1,p2,N)
                t = linspace(0,1,N)';
                pts = p1.*(1-t) + p2.*t;
            end

            function drawSquare(app)
                traj = app.calcSquarePts();
            
            
                % ===== 初值：用当前UI角度（用于连续性/平滑）=====
                %last = deg2rad([app.EditField.Value, app.EditField2.Value, app.EditField3.Value, app.EditField4.Value]);
                
                for k = 1:size(traj,1)
                    %disp("Traj")
                    %disp(traj(k,:))
                    xd = traj(k,1);
                    yd = traj(k,2);
                    zd = traj(k,3);

                    GotoPoint(app, xd, yd, zd);

                    pause(0.03);

                    if app.isConnected
                        p_real = app.readRealWristXYZ();          % 1x3
                        app.realTraceData = [app.realTraceData; p_real];
                    
                        set(app.hRealTrace, ...
                            'XData', app.realTraceData(:,1), ...
                            'YData', app.realTraceData(:,2), ...
                            'ZData', app.realTraceData(:,3));
                    end

                end
            end

            function squarePts = calcSquarePts(app)
                % ====== 你在这里改参数 ======
                center = [grid2pos(app, app.X_GridEditField.Value), grid2pos(app, app.Y_GridEditField.Value)];   % 方形中心 (x,y) 单位 m
                side   = 0.1;           % 边长 (m) 例如 8cm
                z      = app.PointZEditField.Value;           % 高度 (m)
                n      = 40;             % 每条边点数：20
            
                cx = center(1); cy = center(2);
                h  = side/2;
            
                % 四个角（逆时针）
                p1 = [cx-h, cy-h, z];
                p2 = [cx+h, cy-h, z];
                p3 = [cx+h, cy+h, z];
                p4 = [cx-h, cy+h, z];
            
                % 每条边 n 个点
                e1 = linspace3(app, p1, p2, n);
                e2 = linspace3(app, p2, p3, n);
                e3 = linspace3(app, p3, p4, n);
                e4 = linspace3(app, p4, p1, n);
            
                % 拼接，去掉重复角点（每段第一点=上一段最后一点）
                squarePts = [e1;
                             e2(2:end,:);
                             e3(2:end,:);
                             e4(2:end,:)];
            end
            
            function P = linspace3(~, p0, p1, n)
                t = linspace(0,1,n).';
                P = p0 + (p1 - p0).*t;
            end

            function angle_rad = GotoPoint_old(app, pos_x, pos_y, pos_z)
            
                px = pos_x;
                py = pos_y;
                pz = pos_z;
            
                % ===== link lengths (m) =====
                L1 = 0.077;
                L2 = 0.130;
                L3 = 0.124;
                L4 = 0.126;   % wrist->tool length (if gripper extra, add here)
            
                theta1 = atan2(py, px);
            
                % ===== phi candidate list (tool pitch in r-z plane) =====
                % prefer -90deg, then scan a range
                phi0 = deg2rad(-90);
            
                % 根据你第4关节限位大概给一个可用范围（你可调）
                % 例如允许从 -120° 到 -30° （越往后越“抬起来”）
                phi_scan = linspace(deg2rad(-89), deg2rad(-30), 60);
            
                % 让 -90° 一定排第一，并去重
                phi_list = unique([phi0, phi_scan], 'stable');
            
                % ===== best solution record =====
                bestCost = inf;
                bestTh   = [];
                bestPhi  = NaN;
            
                % ===== sweep all phi =====
                foundVertical = false;
                for phi = phi_list
                    if(foundVertical)
                        break;
                    end
            
                    % --- tool tip -> wrist point (your current method, kept) ---
                    wx = px - L4*cos(phi)*cos(theta1);
                    wy = py - L4*cos(phi)*sin(theta1);
                    wz = pz - L4*sin(phi);
            
                    r = hypot(wx, wy);
                    z = wz - L1;
            
                    % --- 2R reachability ---
                    D = (r^2 + z^2 - L2^2 - L3^2) / (2*L2*L3);
            
                    if abs(D) > 1
                        continue; % this phi makes wrist unreachable
                    end
            
                    % numerical clamp (still good near boundary)
                    D = max(min(D,1),-1);
            
                    % --- try both elbow branches ---
                    for sgn = [1, -1]
                        theta3 = atan2(sgn*sqrt(1-D^2), D);
                        theta2 = atan2(z, r) - atan2(L3*sin(theta3), L2 + L3*cos(theta3));
                        theta4 = phi - theta2 - theta3;
            
                        % ===== joint limits check (same as your setTheta clamp) =====
                        % Convert to degrees in UI convention (before offset)
                        th = [theta1 theta2 theta3 theta4];
                        th_deg = rad2deg(th - app.thetaOffset);
            
                        % idx1..3: [-90,90], idx4: [-119,90]
                        if th_deg(1) < app.theta_limit_deg(1,1) || th_deg(1) > app.theta_limit_deg(1,2),   continue; end
                        if th_deg(2) < app.theta_limit_deg(2,1) || th_deg(2) > app.theta_limit_deg(2,2),   continue; end
                        if th_deg(3) < app.theta_limit_deg(3,1) || th_deg(3) > app.theta_limit_deg(3,2),   continue; end
                        if th_deg(4) < app.theta_limit_deg(4,1) || th_deg(4) > app.theta_limit_deg(4,2),   continue; end
            
                        % ===== cost function: prefer phi near -90°, and avoid huge wrist angle =====
                        % 你可以调权重：wPhi 越大越“死命保持竖直”
                        wPhi = 100;
                        wWrist = 1;
                        cost = wPhi*abs(phi - phi0) + wWrist*abs(theta4);
            
                        if cost < bestCost
                            bestCost = cost;
                            bestTh   = th;
                            bestPhi  = phi;
                            if(phi == deg2rad(-90))
                                foundVertical = true;
                                disp("Found Vertical");
                                break; 
                            end
                        end
                    end
                end


                % ===== apply best solution =====
                if isempty(bestTh)
                    disp("IK failed: no feasible solution in phi range + joint limits.");
                    return;
                end
            
                th_deg = rad2deg(bestTh - app.thetaOffset);
            
                app.setTheta(1, th_deg(1), false);
                app.setTheta(2, th_deg(2), false);
                app.setTheta(3, th_deg(3), false);
                app.setTheta(4, th_deg(4), false);
                angle_rad = bestPhi;
                fprintf("Chosen phi = %.1f deg, cost=%.3f\n", rad2deg(bestPhi), bestCost);
            end

            function angle_rad = GotoPoint(app, pos_x, pos_y, pos_z)
            
                px = pos_x;
                py = pos_y;
                pz = pos_z;
            
                % ===== link lengths (m) =====
                L1 = 0.077;
                L2 = 0.130;
                L3 = 0.124;
                L4 = 0.126;   % wrist->tool length (if gripper extra, add here)
            
                theta1 = atan2(py, px);
            
                % ===== phi candidate list (tool pitch in r-z plane) =====
                % prefer -90deg, then scan a range
                phi0 = -pi/2;
            
                % 根据你第4关节限位大概给一个可用范围（你可调）
                % 例如允许从 -120° 到 -30° （越往后越“抬起来”）
                phi_scan = linspace(deg2rad(-120), deg2rad(-30), 60);
            
                % 让 -90° 一定排第一，并去重
                phi_list = unique([phi0, phi_scan], 'stable');
            
                % ===== best solution record =====
                bestCost = inf;
                bestTh   = [];
                bestPhi  = NaN;
            
                % ===== sweep all phi =====
                foundVertical = false;
                for phi = phi_list
                    if(foundVertical)
                        break;
                    end
            
                    % --- tool tip -> wrist point (your current method, kept) ---
                    wx = px - L4*cos(phi)*cos(theta1);
                    wy = py - L4*cos(phi)*sin(theta1);
                    wz = pz - L4*sin(phi);
            
                    r = hypot(wx, wy);
                    z = wz - L1;
            
                    % --- 2R reachability ---
                    D = (r^2 + z^2 - L2^2 - L3^2) / (2*L2*L3);
            
                    if abs(D) > 1
                        continue; % this phi makes wrist unreachable
                    end
            
                    % numerical clamp (still good near boundary)
                    D = max(min(D,1),-1);
            
                    % --- try both elbow branches ---
                    for sgn = [1, -1]
                        theta3 = atan2(sgn*sqrt(1-D^2), D);
                        theta2 = atan2(z, r) - atan2(L3*sin(theta3), L2 + L3*cos(theta3));
                        theta4 = phi - theta2 - theta3;
            
                        % ===== joint limits check (same as your setTheta clamp) =====
                        % Convert to degrees in UI convention (before offset)
                        th = [theta1 theta2 theta3 theta4];
                        th_deg = rad2deg(th - app.thetaOffset);
            
                        % idx1..3: [-90,90], idx4: [-119,90]
                        if th_deg(1) < app.theta_limit_deg(1,1) || th_deg(1) > app.theta_limit_deg(1,2),   continue; end
                        if th_deg(2) < app.theta_limit_deg(2,1) || th_deg(2) > app.theta_limit_deg(2,2),   continue; end
                        if th_deg(3) < app.theta_limit_deg(3,1) || th_deg(3) > app.theta_limit_deg(3,2),   continue; end
                        if th_deg(4) < app.theta_limit_deg(4,1) || th_deg(4) > app.theta_limit_deg(4,2),   continue; end
            
                        % ===== cost function: prefer phi near -90°, and avoid huge wrist angle =====
                        % 你可以调权重：wPhi 越大越“死命保持竖直”
                        wPhi = 100;
                        wWrist = 1;
                        cost = wPhi*abs(phi - phi0) + wWrist*abs(theta4);
            
                        if cost < bestCost
                            bestCost = cost;
                            bestTh   = th;
                            bestPhi  = phi;
                            if(phi == -pi/2)
                                foundVertical = true;
                                disp("Found Vertical");
                                break; 
                            end
                        end
                    end
                end
            
                % ===== apply best solution =====
                if isempty(bestTh)
                    disp("IK failed: no feasible solution in phi range + joint limits.");
                    return;
                end

                
                th_deg = rad2deg(bestTh - app.thetaOffset);
                fprintf("Chosen phi = %.1f deg, cost=%.3f\n", rad2deg(bestPhi), bestCost);
                
                v_sync = zeros(1,4);
                a_sync = zeros(1,4);

                start_pos = app.read_all_current_pos();
                %disp("Start Pos: ");
                %disp(start_pos)

                distance = th_deg-start_pos;
                %disp("Distance: ")
                %disp(distance)

                abs_distance = abs(distance);

                Dmax = max(abs_distance);
                if Dmax < 1e-6
                    return
                end
                
                for k = 1:numel(th_deg)
                    [v_sync(k), a_sync(k)] = app.ratio(Dmax, distance(k), app.VELOCITY_DEG, app.ACCEL_DEG);
                end

                app.setTheta_sync(1, th_deg(1), false, v_sync(1), a_sync(1));
                app.setTheta_sync(2, th_deg(2), false, v_sync(2), a_sync(2));
                app.setTheta_sync(3, th_deg(3), false, v_sync(3), a_sync(3));
                app.setTheta_sync(4, th_deg(4), false, v_sync(4), a_sync(4));
                angle_rad = bestPhi;

                % set_omega(app, th_deg);
            
            end


            function theta_ui = motorRad2uiRad(app, theta_motor)
                % motor = sign*ui - offset
                % => ui = (motor + offset) ./ sign
                theta_ui = (theta_motor) ./ app.thetaSign(1:4);
            end

            function p_wrist = readRealWristXYZ(app)
                % 读4个关节当前位置（Dynamixel position）
                ids = app.DXL_IDS(1:4);
            
                pos = zeros(1,4);
                for k = 1:4
                    pos(k) = read4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, ids(k), app.ADDR_PRO_PRESENT_POSITION);
                end
            
                % position -> theta_motor(rad)
                theta_motor = app.pos2rad(pos);     % 1x4 rad（电机角，含实际方向）
            
                % motor角 -> UI角（去掉 sign/offset，保证跟 fk_T_all 输入一致）
                theta_ui = app.motorRad2uiRad(theta_motor);
            
                % 用FK算点
                P = app.fk_points(theta_ui);   % 3x5
            
                % 腕点 = 第3节末端 = P(:,4)
                p_wrist = P(:,4).';            % 1x3
            end

            function theta1 = readTheta1(app)
                if(app.isConnected)
                    theta1 = -read4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, 11, app.ADDR_PRO_PRESENT_POSITION);
                else
                    theta1 = 0;
                end
            end

            function gripper_hold(app)
                setTheta(app, 5, app.GRIP_HOLD_POS);
                pause(0.2);
            end

            function gripper_release(app)
                setTheta(app, 5, app.GRIP_RELEASE_POS);
                pause(0.2);
            end

            function real_pos = grid2pos(~, grid_pos)
                real_pos = grid_pos/7.5*0.205;
            end

            function grid_pos = pos2grid(~, real_pos)
                grid_pos = real_pos/0.205*7.5;
            end

            function set_position_mode(app)
                if(app.isConnected)
                    for id = app.DXL_IDS
                        write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_OPERATING_MODE, 3);
                    end
                    disp("Switched to Position Control Mode");
                else
                    disp("Not Connected")
                end
            end

            function set_velocity_mode(app)
                if(app.isConnected)
                    for id = app.DXL_IDS
                        write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_PRO_OPERATING_MODE, 1);
                    end
                    disp("Switched to Velocity Control Mode");
                else
                    disp("Not Connected")
                end
            end
                

            function reached = GotoPoint_Angle(app, pos_x, pos_y, pos_z, w_angle)
            
                px = pos_x;
                py = pos_y;
                pz = pos_z;
            
                % ===== link lengths (m) =====
                L1 = 0.077;
                L2 = 0.130;
                L3 = 0.124;
                L4 = 0.126;   % wrist->tool length (if gripper extra, add here)
            
                theta1 = atan2(py, px);
            
                phi = w_angle;
                wx = px - L4*cos(phi)*cos(theta1);
                wy = py - L4*cos(phi)*sin(theta1);
                wz = pz - L4*sin(phi);

                r = hypot(wx, wy);
                z = wz - L1;
        
                % --- 2R reachability ---
                D = (r^2 + z^2 - L2^2 - L3^2) / (2*L2*L3);

                if abs(D) > 1
                    disp("Can't Reach Position"); % this phi makes wrist unreachable
                    reached = false;
                    return;
                end

                D = max(min(D,1),-1);

                theta3_candidates = [atan2(sqrt(1-D^2), D),-atan2(sqrt(1-D^2), D)];
                theta3 = min(theta3_candidates);
                theta2 = atan2(z, r) - atan2(L3*sin(theta3), L2 + L3*cos(theta3));
                theta4 = phi - theta2 - theta3;
        
                % ===== joint limits check (same as your setTheta clamp) =====
                % Convert to degrees in UI convention (before offset)
                th = [theta1 theta2 theta3 theta4];
                th_deg = rad2deg(th - app.thetaOffset);
    
                % idx1..3: [-90,90], idx4: [-119,90]
                if th_deg(1) < -90 || th_deg(1) > 90,   disp("Value out of limit"); return; end
                if th_deg(2) < -90 || th_deg(2) > 90,   disp("Value out of limit"); return; end
                if th_deg(3) < -90 || th_deg(3) > 90,   disp("Value out of limit"); return; end
                if th_deg(4) < -115 || th_deg(4) > 90,  disp("Value out of limit"); return; end
            
                app.setTheta(1, th_deg(1), false);
                app.setTheta(2, th_deg(2), false);
                app.setTheta(3, th_deg(3), false);
                app.setTheta(4, th_deg(4), false);

                reached = true;
            end

            function angle = pick_block(app, pos_x, pos_y, pos_z)

                unify_pos = 0.08;
                wait_pos_z = pos_z+0.08;

                u_pos_x = pos_x/sqrt(pos_x^2 + pos_y^2)*unify_pos;
                u_pos_y = pos_y/sqrt(pos_x^2 + pos_y^2)*unify_pos;

                theta1 = readTheta1(app);
                init_pos_x = unify_pos*cos(theta1);
                init_pos_y = unify_pos*sin(theta1);
                
                GotoPoint(app, init_pos_x, init_pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint(app, u_pos_x, u_pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint(app, pos_x, pos_y, wait_pos_z);
                pause(1);
                gripper_release(app);
                angle = GotoPoint(app, pos_x, pos_y, pos_z);
                disp(angle)
                pause(1);
                gripper_hold(app);
                pause(1);
                GotoPoint(app, pos_x, pos_y, pos_z+0.01);
                pause(0.5)
                GotoPoint(app, pos_x, pos_y, wait_pos_z);
                pause(1);
                GotoPoint(app, u_pos_x, u_pos_y, wait_pos_z);
                disp("Pick Block Endded");
            end

            function put_block(app, pos_x, pos_y, pos_z)
                unify_pos = 0.08;
                wait_pos_z = pos_z+0.08;

                u_pos_x = pos_x/sqrt(pos_x^2 + pos_y^2)*unify_pos;
                u_pos_y = pos_y/sqrt(pos_x^2 + pos_y^2)*unify_pos;

                theta1 = readTheta1(app);
                init_pos_x = unify_pos*cos(theta1);
                init_pos_y = unify_pos*sin(theta1);
                
                GotoPoint(app, init_pos_x, init_pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint(app, u_pos_x, u_pos_y, wait_pos_z);
                pause(1);
                GotoPoint(app, pos_x, pos_y, wait_pos_z);
                pause(1);
                GotoPoint(app, pos_x, pos_y, pos_z);
                pause(1);
                gripper_release(app);
                pause(1);
                GotoPoint(app, pos_x, pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint(app, u_pos_x, u_pos_y, wait_pos_z);
                disp("Put Block Endded");
            end
    
            function pick_block_angle(app, pos_x, pos_y, pos_z)
                unify_pos = 0.08;
                wait_pos_z = 0.1;

                u_pos_x = pos_x/sqrt(pos_x^2 + pos_y^2)*unify_pos;
                u_pos_y = pos_y/sqrt(pos_x^2 + pos_y^2)*unify_pos;

                theta1 = readTheta1(app);
                init_pos_x = unify_pos*cos(theta1);
                init_pos_y = unify_pos*sin(theta1);
                
                GotoPoint(app, init_pos_x, init_pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint(app, u_pos_x, u_pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint_Angle(app, pos_x, pos_y, wait_pos_z, 0);
                pause(0.3);
                gripper_release(app);
                pause(0.3);
                GotoPoint_Angle(app, pos_x, pos_y, pos_z, 0);
                pause(0.3);
                gripper_hold(app);
                pause(0.3);
                GotoPoint_Angle(app, pos_x, pos_y, wait_pos_z, 0);
                pause(0.3);
                GotoPoint(app, pos_x, pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint(app, u_pos_x, u_pos_y, wait_pos_z);
                disp("Pick Block with Angle Endded");
            end

            function put_block_angle(app, pos_x, pos_y, pos_z, angle)
                disp("Put with angle: ")
                disp(angle)
                unify_pos = 0.08;
                wait_pos_z = pos_z+0.08;

                u_pos_x = pos_x/sqrt(pos_x^2 + pos_y^2)*unify_pos;
                u_pos_y = pos_y/sqrt(pos_x^2 + pos_y^2)*unify_pos;

                theta1 = readTheta1(app);
                init_pos_x = unify_pos*cos(theta1);
                init_pos_y = unify_pos*sin(theta1);
                
                GotoPoint(app, init_pos_x, init_pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint(app, u_pos_x, u_pos_y, wait_pos_z);
                pause(1);
                GotoPoint(app, pos_x, pos_y, wait_pos_z);
                pause(1);
                reached = GotoPoint_Angle(app, pos_x, pos_y, pos_z, angle);
                if(~reached)
                    GotoPoint(app, pos_x, pos_y, pos_z);
                end
                pause(1);
                gripper_release(app);
                pause(1);
                GotoPoint(app, pos_x, pos_y, wait_pos_z);
                pause(0.3);
                GotoPoint(app, u_pos_x, u_pos_y, wait_pos_z);
                disp("Put Block Endded");
            end


            function vel_tick = degps2velTick(~, vel_degps)
                    RPM_PER_UNIT = 0.229;
                    DEG_PER_RPM  = 6.0;
                    MAX_UNIT = 1023;
                    vel_rpm = vel_degps / DEG_PER_RPM;
                    vel_unit = vel_rpm / RPM_PER_UNIT;
                    vel_tick = int32(round(vel_unit));
                    if(vel_tick == 0)
                        vel_tick = 1;
                    end
                    vel_tick = min(max(vel_tick, -MAX_UNIT), MAX_UNIT);
            end


            function acc_tick = degps2accelTick(~, acc_degps2)
                ACC_REV_PER_MIN2_PER_UNIT = 214.577;  % Dynamixel spec
                DEG_PER_REV = 360.0;
                SEC_PER_MIN = 60.0;
                MAX_UNIT = 32767;   % Profile Acceleration register limit
            
                % deg/s^2 -> rev/min^2
                acc_rev_min2 = acc_degps2 / DEG_PER_REV * (SEC_PER_MIN^2);
            
                % rev/min^2 -> Dynamixel unit
                acc_unit = acc_rev_min2 / ACC_REV_PER_MIN2_PER_UNIT;
            
                acc_tick = int32(round(acc_unit));
                if(acc_tick == 0)
                    acc_tick = 1;
                end
                acc_tick = min(max(acc_tick, 0), MAX_UNIT);
            end

            function set_profile_velocity_degps(app, idx, vel)
                if(app.isConnected)
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, idx, app.ADDR_PRO_PROFILE_VELOCITY, app.degps2velTick(vel));
                else
                    disp("Can't set profile velocity. Robot Not Connected")
                end
            end

            function set_profile_acceleration_degps2(app, idx, accel)
                if(app.isConnected)
                    write4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, idx, app.ADDR_PRO_PROFILE_ACCELERATION, app.degps2accelTick(accel));
                else
                    disp("Can't set profile accel. Robot Not Connected")
                end
            end

            function current_pos = read_all_current_pos(app)
                ids = app.DXL_IDS(1:4);
                current_pos = zeros(1, numel(ids));
                if(app.isConnected)
                    for k = 1:numel(ids)
                        current_pos(k) = pos2deg(app, read4ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL_IDS(k), app.ADDR_PRO_PRESENT_POSITION));
                    end
                else
                    current_pos = app.theta_current(1:4);
                    disp("Can't Read Current Position. Robot Not Connected")
                end
            end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

                disp("Started Running");
                % 3D 视图初始化
                axis(app.UIAxes, 'equal');
                grid(app.UIAxes, 'on');
                view(app.UIAxes, 3);
                xlabel(app.UIAxes,'X'); ylabel(app.UIAxes,'Y'); zlabel(app.UIAxes,'Z');
            
                % 合理的显示范围（单位 m）
                R = 0.130+0.124+0.126+0.10;
                xlim(app.UIAxes, [-R R]);
                ylim(app.UIAxes, [-R R]);
                zlim(app.UIAxes, [-R R]);


                app.UIAxes.NextPlot = 'add';  % 允许更新不重置
            
                % 先创建一个空的线对象
                app.hArmLine = plot3(app.UIAxes, nan, nan, nan, '-o', 'LineWidth', 2);
                app.hGripperTrace = plot3(app.UIAxes, nan, nan, nan, '-', 'LineWidth', 2, 'Color', 'r');
                app.gripperTraceData = [];
                app.hRealTrace = plot3(app.UIAxes, nan, nan, nan, '-', 'LineWidth', 2, 'Color', 'g');   % 绿色=真实轨迹
                app.realTraceData = [];


                % 初始角度
                app.Slider.Value = 0; app.EditField.Value = 0;
                app.Slider2.Value = 0; app.EditField2.Value = 0;
                app.Slider3.Value = 0; app.EditField3.Value = 0;
                app.Slider4.Value = 0; app.EditField4.Value = 0;
                app.Slider5.Value = 0; app.EditField5.Value = 0;
                app.X_GridEditField.Value = 4; app.Y_GridEditField.Value = 4; app.PointZEditField.Value = 0.1;
                app.theta_current = [0,0,0,0,0];
                
                app.AngleWEditField.Value = -90;
                app.X_PickEditField.Value = 7;
                app.Y_PickEditField.Value = 0;
                app.Z_PickEditField.Value = 0.045;

                app.X_PutEditField.Value = 6.5;
                app.Y_PutEditField.Value = 6.5;
                app.Z_PutEditField.Value = 0.05;

                app.DXL_IDS = [app.DXL_ID1 app.DXL_ID2 app.DXL_ID3 app.DXL_ID4 app.DXL_GRIP];

                app.updateTimer = timer( ...
                'ExecutionMode','fixedRate', ...
                'Period',0.05, ...          % 0.05s = 20Hz
                'TimerFcn',@(~,~)app.onTimerTick());
        
                app.redraw();
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            disconnectDxl(app);
            delete(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.ThetaControlChanged(1, app.Slider.Value);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.ThetaSliderChanging(event, 1);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.ThetaControlChanged(1, app.EditField.Value);
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            app.ThetaControlChanged(2, event.Value);
        end

        % Value changing function: Slider2
        function Slider2ValueChanging(app, event)
            app.ThetaSliderChanging(event, 2);
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            app.ThetaControlChanged(2, event.Value);
        end

        % Value changed function: Slider3
        function Slider3ValueChanged(app, event)
            app.ThetaControlChanged(3, event.Value);
        end

        % Value changing function: Slider3
        function Slider3ValueChanging(app, event)
            app.ThetaSliderChanging(event, 3);
        end

        % Value changed function: EditField3
        function EditField3ValueChanged(app, event)
            app.ThetaControlChanged(3, event.Value);
        end

        % Value changed function: Slider4
        function Slider4ValueChanged(app, event)
            app.ThetaControlChanged(4, event.Value);
        end

        % Value changing function: Slider4
        function Slider4ValueChanging(app, event)
            app.ThetaSliderChanging(event, 4);
        end

        % Value changed function: EditField4
        function EditField4ValueChanged(app, event)
            app.ThetaControlChanged(4, event.Value);
        end

        % Value changed function: Slider5
        function Slider5ValueChanged(app, event)
            app.ThetaControlChanged(5, event.Value);
        end

        % Value changing function: Slider5
        function Slider5ValueChanging(app, event)
            app.ThetaSliderChanging(event, 5);
        end

        % Value changed function: EditField5
        function EditField5ValueChanged(app, event)
            app.ThetaControlChanged(5, event.Value);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            connectDxl(app);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            disconnectDxl(app);
        end

        % Button pushed function: GotoPointButton
        function GotoPointButtonPushed(app, event)
            GotoPoint(app, grid2pos(app, app.X_GridEditField.Value), grid2pos(app, app.Y_GridEditField.Value), app.PointZEditField.Value);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            drawSquare(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.gripperTraceData = [];

            % 清空红色轨迹线（但不影响视角/坐标轴）
            if ~isempty(app.hGripperTrace) && isvalid(app.hGripperTrace)
                set(app.hGripperTrace, 'XData', nan, 'YData', nan, 'ZData', nan);
                app.realTraceData = [];
                set(app.hRealTrace, 'XData', nan, 'YData', nan, 'ZData', nan);
            end
            
        end

        % Button pushed function: GripperHoldButton
        function GripperHoldButtonPushed(app, event)
            gripper_hold(app);
        end

        % Button pushed function: GripperReleaseButton
        function GripperReleaseButtonPushed(app, event)
            gripper_release(app);
        end

        % Button pushed function: GotowithAngleButton
        function GotowithAngleButtonPushed(app, event)
            GotoPoint_Angle(app, grid2pos(app, app.X_GridEditField.Value), grid2pos(app, app.Y_GridEditField.Value), app.PointZEditField.Value, deg2rad(app.AngleWEditField.Value));
        end

        % Button pushed function: PickButton
        function PickButtonPushed(app, event)
            pick_block(app, grid2pos(app, app.X_PickEditField.Value), grid2pos(app, app.Y_PickEditField.Value), app.Z_PickEditField.Value);
        end

        % Button pushed function: PutButton
        function PutButtonPushed(app, event)
            put_block(app, grid2pos(app, app.X_PutEditField.Value), grid2pos(app, app.Y_PutEditField.Value), app.Z_PutEditField.Value);
        end

        % Button pushed function: PickandPutButton
        function PickandPutButtonPushed(app, event)
            w_angle = pick_block(app, grid2pos(app, app.X_PickEditField.Value), grid2pos(app, app.Y_PickEditField.Value),app.Z_PickEditField.Value);
            pause(0.3);
            put_block_angle(app, grid2pos(app, app.X_PutEditField.Value), grid2pos(app, app.Y_PutEditField.Value), app.Z_PutEditField.Value, w_angle);
        end

        % Button pushed function: PickwithAngleButton
        function PickwithAngleButtonPushed(app, event)
            pick_block_angle(app, grid2pos(app, app.X_PickEditField.Value), grid2pos(app, app.Y_PickEditField.Value), app.Z_PickEditField.Value);
        end

        % Button pushed function: GotoPointSmoothButton
        function GotoPointSmoothButtonPushed(app, event)
            GotoPoint(app, grid2pos(app, app.X_GridEditField.Value), grid2pos(app, app.Y_GridEditField.Value), app.PointZEditField.Value);
        end

        % Button pushed function: PositionModeButton
        function PositionModeButtonPushed(app, event)
            set_position_mode(app)
        end

        % Button pushed function: VelocityModeButton
        function VelocityModeButtonPushed(app, event)
            set_velocity_mode(app)
        end

        % Button pushed function: ReadCurrentPosButton
        function ReadCurrentPosButtonPushed(app, event)
            theta_all = app.read_all_current_pos();
            disp("Position Get");
            disp(theta_all);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-120 120];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'Slider2';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Limits = [-90 90];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.ValueChangingFcn = createCallbackFcn(app, @Slider2ValueChanging, true);
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.Slider3Label = uilabel(app.UIFigure);
            app.Slider3Label.Text = 'Slider3';
            app.Slider3 = uislider(app.UIFigure);
            app.Slider3.Limits = [-90 90];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged, true);
            app.Slider3.ValueChangingFcn = createCallbackFcn(app, @Slider3ValueChanging, true);
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3.ValueChangedFcn = createCallbackFcn(app, @EditField3ValueChanged, true);
            app.Slider4Label = uilabel(app.UIFigure);
            app.Slider4Label.Text = 'Slider4';
            app.Slider4 = uislider(app.UIFigure);
            app.Slider4.Limits = [-115 90];
            app.Slider4.ValueChangedFcn = createCallbackFcn(app, @Slider4ValueChanged, true);
            app.Slider4.ValueChangingFcn = createCallbackFcn(app, @Slider4ValueChanging, true);
            app.EditField4Label = uilabel(app.UIFigure);
            app.EditField4Label.Text = 'Edit Field4';
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4.ValueChangedFcn = createCallbackFcn(app, @EditField4ValueChanged, true);
            app.Slider5Label = uilabel(app.UIFigure);
            app.Slider5Label.Text = 'Slider5';
            app.Slider5 = uislider(app.UIFigure);
            app.Slider5.Limits = [-90 90];
            app.Slider5.ValueChangedFcn = createCallbackFcn(app, @Slider5ValueChanged, true);
            app.Slider5.ValueChangingFcn = createCallbackFcn(app, @Slider5ValueChanging, true);
            app.EditField5Label = uilabel(app.UIFigure);
            app.EditField5Label.Text = 'Edit Field5';
            app.EditField5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField5.ValueChangedFcn = createCallbackFcn(app, @EditField5ValueChanged, true);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.GotoPointButton = uibutton(app.UIFigure, 'push');
            app.GotoPointButton.ButtonPushedFcn = createCallbackFcn(app, @GotoPointButtonPushed, true);
            app.GotoPointButton.Text = 'Go to Point';
            app.X_GridEditFieldLabel = uilabel(app.UIFigure);
            app.X_GridEditFieldLabel.Text = 'X_Grid';
            app.X_GridEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_GridEditFieldLabel = uilabel(app.UIFigure);
            app.Y_GridEditFieldLabel.Text = 'Y_Grid';
            app.Y_GridEditField = uieditfield(app.UIFigure, 'numeric');
            app.PointZEditFieldLabel = uilabel(app.UIFigure);
            app.PointZEditFieldLabel.Text = 'PointZ';
            app.PointZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.GripperHoldButton = uibutton(app.UIFigure, 'push');
            app.GripperHoldButton.ButtonPushedFcn = createCallbackFcn(app, @GripperHoldButtonPushed, true);
            app.GripperHoldButton.Text = 'GripperHold';
            app.GripperReleaseButton = uibutton(app.UIFigure, 'push');
            app.GripperReleaseButton.ButtonPushedFcn = createCallbackFcn(app, @GripperReleaseButtonPushed, true);
            app.GripperReleaseButton.Text = 'GripperRelease';
            app.AngleWEditFieldLabel = uilabel(app.UIFigure);
            app.AngleWEditFieldLabel.Text = 'AngleW';
            app.AngleWEditField = uieditfield(app.UIFigure, 'numeric');
            app.GotowithAngleButton = uibutton(app.UIFigure, 'push');
            app.GotowithAngleButton.ButtonPushedFcn = createCallbackFcn(app, @GotowithAngleButtonPushed, true);
            app.GotowithAngleButton.Text = 'Go to with Angle';
            app.X_PickEditFieldLabel = uilabel(app.UIFigure);
            app.X_PickEditFieldLabel.Text = 'X_Pick';
            app.X_PickEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_PickEditFieldLabel = uilabel(app.UIFigure);
            app.Y_PickEditFieldLabel.Text = 'Y_Pick';
            app.Y_PickEditField = uieditfield(app.UIFigure, 'numeric');
            app.Z_PickEditFieldLabel = uilabel(app.UIFigure);
            app.Z_PickEditFieldLabel.Text = 'Z_Pick';
            app.Z_PickEditField = uieditfield(app.UIFigure, 'numeric');
            app.X_PutEditFieldLabel = uilabel(app.UIFigure);
            app.X_PutEditFieldLabel.Text = 'X_Put';
            app.X_PutEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_PutEditFieldLabel = uilabel(app.UIFigure);
            app.Y_PutEditFieldLabel.Text = 'Y_Put';
            app.Y_PutEditField = uieditfield(app.UIFigure, 'numeric');
            app.Z_PutEditFieldLabel = uilabel(app.UIFigure);
            app.Z_PutEditFieldLabel.Text = 'Z_Put';
            app.Z_PutEditField = uieditfield(app.UIFigure, 'numeric');
            app.PickButton = uibutton(app.UIFigure, 'push');
            app.PickButton.ButtonPushedFcn = createCallbackFcn(app, @PickButtonPushed, true);
            app.PickButton.Text = 'Pick';
            app.PutButton = uibutton(app.UIFigure, 'push');
            app.PutButton.ButtonPushedFcn = createCallbackFcn(app, @PutButtonPushed, true);
            app.PutButton.Text = 'Put';
            app.PickandPutButton = uibutton(app.UIFigure, 'push');
            app.PickandPutButton.ButtonPushedFcn = createCallbackFcn(app, @PickandPutButtonPushed, true);
            app.PickandPutButton.Text = 'Pick and Put';
            app.PickwithAngleButton = uibutton(app.UIFigure, 'push');
            app.PickwithAngleButton.ButtonPushedFcn = createCallbackFcn(app, @PickwithAngleButtonPushed, true);
            app.PickwithAngleButton.Text = 'Pick with Angle';
            app.GotoPointSmoothButton = uibutton(app.UIFigure, 'push');
            app.GotoPointSmoothButton.ButtonPushedFcn = createCallbackFcn(app, @GotoPointSmoothButtonPushed, true);
            app.GotoPointSmoothButton.Text = 'Go to Point (Smooth)';
            app.PositionModeButton = uibutton(app.UIFigure, 'push');
            app.PositionModeButton.ButtonPushedFcn = createCallbackFcn(app, @PositionModeButtonPushed, true);
            app.PositionModeButton.Text = 'Position Mode';
            app.VelocityModeButton = uibutton(app.UIFigure, 'push');
            app.VelocityModeButton.ButtonPushedFcn = createCallbackFcn(app, @VelocityModeButtonPushed, true);
            app.VelocityModeButton.Text = 'Velocity Mode';
            app.ReadCurrentPosButton = uibutton(app.UIFigure, 'push');
            app.ReadCurrentPosButton.ButtonPushedFcn = createCallbackFcn(app, @ReadCurrentPosButtonPushed, true);
            app.ReadCurrentPosButton.Text = 'ReadCurrentPos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end